classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MadeByTahaBaraziLabel       matlab.ui.control.Label
        ConnectionSettingPanel      matlab.ui.container.Panel
        BaudRateEditField           matlab.ui.control.EditField
        BaudRateEditFieldLabel      matlab.ui.control.Label
        DisConnectedButton          matlab.ui.control.StateButton
        PortEditField               matlab.ui.control.EditField
        PortEditFieldLabel          matlab.ui.control.Label
        StartButton                 matlab.ui.control.StateButton
        Label                       matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        OpenloopTab                 matlab.ui.container.Tab
        CurrentAGauge               matlab.ui.control.SemicircularGauge
        CurrentAGaugeLabel          matlab.ui.control.Label
        OpenLoopsettingsLabel       matlab.ui.control.Label
        SpeedKnob                   matlab.ui.control.Knob
        SpeedKnobLabel              matlab.ui.control.Label
        DirectionKnob               matlab.ui.control.DiscreteKnob
        DirectionKnobLabel          matlab.ui.control.Label
        CloseloopTab                matlab.ui.container.Tab
        ClosedLoopsettingsLabel     matlab.ui.control.Label
        StepSettingPanel            matlab.ui.container.Panel
        postionmmSlider             matlab.ui.control.Slider
        postionmmSliderLabel        matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        SignalTypeButtonGroup       matlab.ui.container.ButtonGroup
        SqrButton                   matlab.ui.control.RadioButton
        SinButton                   matlab.ui.control.RadioButton
        StepButton                  matlab.ui.control.RadioButton
        SinwaveSettingButtonGroup   matlab.ui.container.ButtonGroup
        HzButton_5                  matlab.ui.control.RadioButton
        HzButton_4                  matlab.ui.control.RadioButton
        frequencyLabel              matlab.ui.control.Label
        Amp015mmEditField           matlab.ui.control.NumericEditField
        Amp015mmLabel               matlab.ui.control.Label
        HzButton_3                  matlab.ui.control.RadioButton
        HzButton_2                  matlab.ui.control.RadioButton
        HzButton                    matlab.ui.control.RadioButton
        CurrentPostionmmGauge       matlab.ui.control.LinearGauge
        CurrentPostionmmGaugeLabel  matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        ModeButtonGroup             matlab.ui.container.ButtonGroup
        ClosedLoopButton            matlab.ui.control.RadioButton
        OpenLoopButton              matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function StartButtonPushed(app, event)
            openloop=app.OpenLoopButton.Value;
            closeloop=app.ClosedLoopButton.Value;
            iden=app.IdentifcationButton.Value;
            global S
           flushinput(S);
           
          if(iden) %Ident mode        
             m="i";
             fprintf(S,m);  
             
            filepath = fullfile('C:', 'Users', 'Taha Barazy', 'Documents', 'received_data.txt');
            file = fopen(filepath,'w');
            %app.ReadDataEditField.Value="taha";
        while 1
           if S.BytesAvailable > 0
              data = fgets(S);
         %  elements = strsplit(data);
        
        % Convert each element to an integer
       %counter = str2double(elements{1});
       % input = str2double(elements{2});
        %output=str2double(elements{3});
        %error = str2double(elements{4});
 
        file = fopen(filepath,'a');
        fprintf(file,data)%, '%d %d %d %d\n',counter,input,output,error);
        fclose(file);
       
    end
        end 
          else if(openloop)
                 data=" ";
                 dir=app.DirectionKnob.Value;
                 speed=app.SpeedKnob.Value;
                 m=['o ' dir ' ' num2str(speed)];
                 fprintf(S,m);
                 flushinput(S);
                 pause(1);
                 
                 while openloop
                 data = fgets(S);
                 app.ReadDataEditField.Value=data;
                 pause(0.001);
                 end
                 
                 
                % app.CurrentGauge.Value=str2double(data);
          else   if(closeloop) 
                  data="";
                   if(app.ActivateSwitch.Value == "On")
                       app.Lamp.Color=[0,1,0];
                        postion=app.postionmmSlider.Value;
                                     
                         m=['c' ' ' num2str(postion) ' ' num2str(1)];    
                    
                         fprintf(S,m);
                         flushinput(S);
                        pause(1);
                    if S.BytesAvailable > 0
                     data = fgets(S);
                     app.ReadDataEditField.Value=data;
                     pause(1)
                    end
                     
                  end
            end
          end 
        end

            while(S.BytesAvailable)
                fgets(S);
            end 


        end

        % Callback function
        function StopButtonPushed(app, event)
                   global S 
                   m="s";%stop
                   fprintf(S,m); 
                   app.ReadDataEditField.Value=" ";
                   flushinput(S);
        end

        % Callback function
        function connectedButtonPushed(app, event)
           
        end

        % Value changed function: DisConnectedButton
        function DisConnectedButtonValueChanged(app, event)
            value = app.DisConnectedButton.Value;
            global S 
            try
                if value==1
                    instrfind;
                    delete(instrfind)
                    n=app.PortEditField.Value;
                    port=['COM' n];
                    mybaud=app.BaudRateEditField.Value;
                    S=serial(port,'BaudRate',str2num(mybaud));
                    set(S, 'TimeOut', 1);
                    fopen(S);
                    while(S.BytesAvailable)
                        fgets(S);
                    end
                    pause(1);
                    set(app.DisConnectedButton,'BackgroundColor',[0 .5 0])
                    set(app.DisConnectedButton,'Text','Connected')
                else
                    instrfind;
                    delete(instrfind)
                    set(app.DisConnectedButton,'BackgroundColor',[0.64,0.08,0.18])
                    set(app.DisConnectedButton,'Text','DisConnected')
                end
            catch e3
                d = dialog('Position',[300 300 500 300],'Name','My Dialog',"WindowStyle",'modal');
                txt = uicontrol('Parent',d,...
                    'Style','text',...
                    'Position',[100 120 310 80],...
                    'FontSize',18,...
                    'String',{'Port is Not Found 😯 ! !'});
                btn = uicontrol('Parent',d,...
                    'Position',[200 20 70 25],...
                    'String','Close',...
                    'Callback','delete(gcf)')
          
                
            end
        end

        % Callback function
        function ReadPostionButtonValueChanged(app, event)
            value = app.ReadPostionButton.Value;
            CloseLoop=app.ClosedLoopButton.Value;
            global S
            if value
                if(CloseLoop)
                     while S.BytesAvailable > 0 
                    data = fgets(S);                    
                     app.CurrentPostionmmGauge.Value=str2num(data);
                     pause(0.5);
                 end    
               
           end
            else  app.CurrentPostionmmGauge.Value=0;  
            end

        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            openloop=app.OpenLoopButton.Value;
            closeloop=app.ClosedLoopButton.Value;

            global S
           flushinput(S);
        if(value)   
            if(openloop)
                 data=" ";
                 dir=app.DirectionKnob.Value;
                 speed=app.SpeedKnob.Value;
                 m=['o' ' ' dir ' ' num2str(speed)];
                 fprintf(S,m);

                 
                 %app.CurrentAEditField.Value=str2double(data);
          else   if(closeloop)
                   data=" ";
                   
                  if(app.StepButton.Value)
                  postion=app.postionmmSlider.Value;
                  m=['c' ' ' 'step' ' ' num2str(postion)];            
                  else if(app.SinButton.Value)
                          Amp=app.Amp015mmEditField.Value;
                          if(Amp<0)
                              Amp=0;
                          else if(Amp>15)
                              Amp=15;
                          end
                          end
                          if(app.HzButton.Value)
                              m=['c ' 'sin' ' ' num2str(Amp) ' ' '1']; 
                          else if(app.HzButton_2.Value)
                              m=['c ' 'sin' ' ' num2str(Amp) ' ' '1.5'];
                          else if (app.HzButton_3.Value)
                                  m=['c ' 'sin' ' ' num2str(Amp) ' ' '2'];
                           else if (app.HzButton_4.Value)
                                  m=['c ' 'sin' ' ' num2str(Amp) ' ' '2.5'];
                           else if (app.HzButton_5.Value)
                                  m=['c ' 'sin' ' ' num2str(Amp) ' ' '3'];
                           end
                         end
                        end
                     end
                   end
                  else if(app.SqrButton.Value)
                  m=['c ' 'Sqr' ' ' num2str(40) ' ' '1']; 
                        end  
                    end
                 end
           
                
                    fprintf(S,m);
                    pause(1);
                    filePath = fullfile(getenv('USERPROFILE'), 'Desktop', 'Setpoint & Command & FB & Current_data.txt');

                    % فتح الملف وكتابة البيانات
                    fileID = fopen(filePath, 'w');
                    while S.BytesAvailable > 0
                     data = fgets(S);
                     elements = strsplit(data,'\t');
                        if(length(elements)==4)
                     % Convert each element to an integer
                    setpoint = str2double(elements{1});
                    if(isnan(setpoint))
                        setpoint=0;
                    end
                    comm = str2double(elements{2});
                     if(isnan(comm))
                        comm=0;
                    end
                    FB = str2double(elements{3});
                    if(isnan(FB))
                        FB=0;
                    end
                    ILoad = str2double(elements{4});
                    if(isnan(ILoad))
                        ILoad=0;
                    end
                    file = fopen(filePath,'a');
                    fprintf(fileID, '%s\n', data);
                    fclose(fileID);
                     
                    app.CurrentPostionmmGauge.Value=setpoint;
                    app.CurrentAGauge.Value=comm;
                       end
                     pause(0.01)
                   end
             end
            end
            
            
         else
                   m="s";%stop
                   fprintf(S,m); 
                   app.CurrentAGauge.Value=0;
                   app.CurrentPostionmmGauge.Value=0;


        end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.Title = 'Mode';
            app.OpenLoopButton = uiradiobutton(app.ModeButtonGroup);
            app.OpenLoopButton.Text = 'OpenLoop';
            app.OpenLoopButton.Value = true;
            app.ClosedLoopButton = uiradiobutton(app.ModeButtonGroup);
            app.ClosedLoopButton.Text = 'ClosedLoop';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OpenloopTab = uitab(app.TabGroup);
            app.OpenloopTab.Title = 'Openloop';
            app.DirectionKnobLabel = uilabel(app.OpenloopTab);
            app.DirectionKnobLabel.Text = 'Direction';
            app.DirectionKnob = uiknob(app.OpenloopTab, 'discrete');
            app.DirectionKnob.Items = {'Forward', 'Backward'};
            app.DirectionKnob.Value = 'Forward';
            app.SpeedKnobLabel = uilabel(app.OpenloopTab);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.OpenloopTab, 'continuous');
            app.OpenLoopsettingsLabel = uilabel(app.OpenloopTab);
            app.OpenLoopsettingsLabel.Text = 'Open Loop settings';
            app.CurrentAGaugeLabel = uilabel(app.OpenloopTab);
            app.CurrentAGaugeLabel.Text = 'Current (A)';
            app.CurrentAGauge = uigauge(app.OpenloopTab, 'semicircular');
            app.CurrentAGauge.Limits = [0 3];
            app.CloseloopTab = uitab(app.TabGroup);
            app.CloseloopTab.Title = 'Closeloop';
            app.Label_3 = uilabel(app.CloseloopTab);
            app.Label_3.Text = '';
            app.CurrentPostionmmGaugeLabel = uilabel(app.CloseloopTab);
            app.CurrentPostionmmGaugeLabel.Text = 'Current Postion (mm)';
            app.CurrentPostionmmGauge = uigauge(app.CloseloopTab, 'linear');
            app.CurrentPostionmmGauge.Limits = [20 50];
            app.SinwaveSettingButtonGroup = uibuttongroup(app.CloseloopTab);
            app.SinwaveSettingButtonGroup.Title = 'Sin wave Setting:';
            app.HzButton = uiradiobutton(app.SinwaveSettingButtonGroup);
            app.HzButton.Text = '1Hz';
            app.HzButton.Value = true;
            app.HzButton_2 = uiradiobutton(app.SinwaveSettingButtonGroup);
            app.HzButton_2.Text = '1.5Hz';
            app.HzButton_3 = uiradiobutton(app.SinwaveSettingButtonGroup);
            app.HzButton_3.Text = '2Hz';
            app.Amp015mmLabel = uilabel(app.SinwaveSettingButtonGroup);
            app.Amp015mmLabel.Text = 'Amp(0~15mm):';
            app.Amp015mmEditField = uieditfield(app.SinwaveSettingButtonGroup, 'numeric');
            app.Amp015mmEditField.Value = 15;
            app.frequencyLabel = uilabel(app.SinwaveSettingButtonGroup);
            app.frequencyLabel.Text = 'frequency:';
            app.HzButton_4 = uiradiobutton(app.SinwaveSettingButtonGroup);
            app.HzButton_4.Text = '2.5Hz';
            app.HzButton_5 = uiradiobutton(app.SinwaveSettingButtonGroup);
            app.HzButton_5.Text = '3Hz';
            app.SignalTypeButtonGroup = uibuttongroup(app.CloseloopTab);
            app.SignalTypeButtonGroup.Title = 'Signal Type:';
            app.StepButton = uiradiobutton(app.SignalTypeButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.SinButton = uiradiobutton(app.SignalTypeButtonGroup);
            app.SinButton.Text = 'Sin';
            app.SqrButton = uiradiobutton(app.SignalTypeButtonGroup);
            app.SqrButton.Text = 'Sqr';
            app.Label_2 = uilabel(app.CloseloopTab);
            app.Label_2.Text = '';
            app.StepSettingPanel = uipanel(app.CloseloopTab);
            app.StepSettingPanel.Title = 'Step Setting:';
            app.postionmmSliderLabel = uilabel(app.StepSettingPanel);
            app.postionmmSliderLabel.Text = 'postion (mm)';
            app.postionmmSlider = uislider(app.StepSettingPanel);
            app.postionmmSlider.Limits = [20 50];
            app.postionmmSlider.Value = 20;
            app.ClosedLoopsettingsLabel = uilabel(app.CloseloopTab);
            app.ClosedLoopsettingsLabel.Text = 'Closed Loop settings';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'The user interface for command and control of the electro-hydraulic actuator';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.ConnectionSettingPanel = uipanel(app.UIFigure);
            app.ConnectionSettingPanel.Title = 'Connection Setting :';
            app.PortEditFieldLabel = uilabel(app.ConnectionSettingPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ConnectionSettingPanel, 'text');
            app.PortEditField.Value = '10';
            app.DisConnectedButton = uibutton(app.ConnectionSettingPanel, 'state');
            app.DisConnectedButton.ValueChangedFcn = createCallbackFcn(app, @DisConnectedButtonValueChanged, true);
            app.DisConnectedButton.Text = 'DisConnected';
            app.BaudRateEditFieldLabel = uilabel(app.ConnectionSettingPanel);
            app.BaudRateEditFieldLabel.Text = 'BaudRate';
            app.BaudRateEditField = uieditfield(app.ConnectionSettingPanel, 'text');
            app.BaudRateEditField.Value = '115200';
            app.MadeByTahaBaraziLabel = uilabel(app.UIFigure);
            app.MadeByTahaBaraziLabel.Text = 'Made By: Taha Barazi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end